classdef tugas2gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        OriginalTab         matlab.ui.container.Tab
        GridLayout6         matlab.ui.container.GridLayout
        Image               matlab.ui.control.Image
        ProcessedTab        matlab.ui.container.Tab
        GridLayout10        matlab.ui.container.GridLayout
        Image2              matlab.ui.control.Image
        RefrensiTab         matlab.ui.container.Tab
        GridLayout10_2      matlab.ui.container.GridLayout
        Image2_2            matlab.ui.control.Image
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout14        matlab.ui.container.GridLayout
        EditField           matlab.ui.control.EditField
        MaskKonvolusiLabel  matlab.ui.control.Label
        GridLayout13        matlab.ui.container.GridLayout
        GridLayout12        matlab.ui.container.GridLayout
        ProcessButton       matlab.ui.control.Button
        InputGambarButton   matlab.ui.control.Button
        TypeDropDown        matlab.ui.control.DropDown
    end

    
     properties (Access = private)
        selectedFile
        selectedType = "Tipe 1"
        refImg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.selectedType = value;
        end

        % Button pushed function: InputGambarButton
        function InputGambarButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                app.selectedFile = fullfile(path, file);
                
                app.Image.ImageSource = app.selectedFile;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            img = imread(app.selectedFile);
            
            imgprcs = Tugas2Program;
            matrix = app.EditField.Value;
            mask = str2num(char(matrix))

            app.MaskKonvolusiLabel.Text = class(mask);

            if app.selectedType == "Tipe 1"
    
                R = imgprcs.convolution(img(:, :, 1), mask);
                G = imgprcs.convolution(img(:, :, 2), mask);
                B = imgprcs.convolution(img(:, :, 3), mask);

                imgProcessed = cat(3, R, G, B);

            elseif app.selectedType == "Tipe 2"


            end

            app.Image2.ImageSource = imgProcessed; % show img processed
        end

        % Callback function
        function InputGambarReferensiButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                refFile = fullfile(path, file);
                
                imageRef = imread(refFile);
                img = imread(app.selectedFile);
                
                if all(size(imageRef) == size(img)) && all(size(imageRef,3) == size(img,3))
                    app.refImg = imageRef;
                    app.Image2_2.ImageSource = refFile;
                else
                    disp('Unmatched file size or dimension');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.GridLayout2);
            app.TypeDropDown = uidropdown(app.GridLayout12);
            app.TypeDropDown.Items = {'Tipe 1', 'Tipe 2', 'Tipe 3', 'Tipe 4'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Tipe 1';
            app.InputGambarButton = uibutton(app.GridLayout12, 'push');
            app.InputGambarButton.ButtonPushedFcn = createCallbackFcn(app, @InputGambarButtonPushed, true);
            app.InputGambarButton.Text = {'Input'; 'Gambar'};
            app.ProcessButton = uibutton(app.GridLayout12, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.GridLayout13 = uigridlayout(app.GridLayout2);
            app.GridLayout14 = uigridlayout(app.GridLayout2);
            app.MaskKonvolusiLabel = uilabel(app.GridLayout14);
            app.MaskKonvolusiLabel.Text = 'Mask Konvolusi';
            app.EditField = uieditfield(app.GridLayout14, 'text');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.GridLayout6 = uigridlayout(app.OriginalTab);
            app.Image = uiimage(app.GridLayout6);
            app.ProcessedTab = uitab(app.TabGroup);
            app.ProcessedTab.Title = 'Processed';
            app.GridLayout10 = uigridlayout(app.ProcessedTab);
            app.Image2 = uiimage(app.GridLayout10);
            app.RefrensiTab = uitab(app.TabGroup);
            app.RefrensiTab.Title = 'Refrensi';
            app.GridLayout10_2 = uigridlayout(app.RefrensiTab);
            app.Image2_2 = uiimage(app.GridLayout10_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tugas2gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end